classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        USCityEditFieldLabel    matlab.ui.control.Label
        USCityEditField         matlab.ui.control.EditField
        ButtonGroup             matlab.ui.container.ButtonGroup
        ImmediateThreatsButton  matlab.ui.control.ToggleButton
        MapDisplayButton        matlab.ui.control.ToggleButton
        UsefulContactsButton    matlab.ui.control.ToggleButton
        WeatherButton           matlab.ui.control.ToggleButton
        TextArea                matlab.ui.control.TextArea
    end


    methods (Access = public)
    %Weather Button
    function Weatherapp(app,WEATHER,avalue,tempmin,tempmax)
            q = strcat(avalue,',us');
            APPID = 'e1e43fc2a743b11f74756e4c1803fd9e';
            endpoint1 = 'http://api.openweathermap.org/data/2.5/weather';
            endpoint2  = 'http://api.openweathermap.org/data/2.5/forecast';
            
            if WEATHER == 1
                if isempty(avalue) == 0
                    dataf = webread(endpoint1,'q',q,'APPID',APPID);
                    tempdata = dataf.main.temp;
                    weatherdata = dataf.weather.description;
                    str = 'The temperature is currently';
                    temp = ceil(1.8 * (tempdata - 273) + 32);
                    str2 = num2str(ceil(1.8 * (tempdata - 273) + 32));
                    str3 = 'There is currently';
                    %This function prints information
                    fid = fopen('weathertext.txt','w');
                    fprintf(fid,'\nIt is currently %2.0f degrees\n',temp);
                    fprintf(fid,'Low of %2.0f. High of %2.0f.\n',tempmin,tempmax);
                    fprintf(fid,'Pressure is %4.0d kPa and humidity is %2.0d%%\n',dataf.main.pressure,dataf.main.humidity);

                    if temp>=75
                        app.TextArea.Value = strcat({str},{' '},{str2},{(176)},{'F'},{newline},{str3},{' '},{weatherdata},{newline},'It''s hot today. Protect yourself from harmful UV rays by wearing sunglasses or sunscreen!');
                    elseif temp>=60&&temp<=74
                        app.TextArea.Value = strcat({str},{' '},{str2},{(176)},{'F'},{newline},{str3},{' '},{weatherdata},{newline},'Great Weather!');
                    elseif temp>=33&&temp<=59
                        app.TextArea.Value = strcat({str},{' '},{str2},{(176)},{'F'},{newline},{str3},{' '},{weatherdata},{newline},'It''s cold, wear lots of layers and stay warm.');
                    else
                        app.TextArea.Value = strcat({str},{' '},{str2},{(176)},{'F'},{newline},{str3},{' '},{weatherdata},{newline},'It''s below freezing! Please be careful of ice, especially when driving.');
                    end
                    fprintf(fid,'\nDescription: %s\n',lower(dataf.weather.main));
                    fclose(fid);
                else
                d = dialog('Position',[300 300 250 150]);
            
                txt = uicontrol('Parent',d,...
                      'Style','text',...
                      'Position',[20 80 210 40],...
                      'String','Please enter a city. Default is Boston.');
                end
            end
    end
        %Data Functions
        function [dataf,datat]=callapi(app,APPID,q,endpoint,endpoint1)
        %This function calls the API and stores the data
        dataf = webread(endpoint,'q',q,'APPID',APPID);
        datat = webread(endpoint1,'q',q,'APPID',APPID);
        end
        
        function [lon,lat]=coordinates(app,dataf)
        %This function pulls the coordinates from the API for the map
        lon=dataf.coord.lon;
        lat=dataf.coord.lat;
        end 
        
        function [tempmax,tempmin]=tempmaxmin(app,dataf)
        %This function calculates the maximum and minimum temperatures in
        %Fahrenheit
        tempmax=1.8 * (dataf.main.temp_max -273)+32;
        tempmin=1.8 * (dataf.main.temp_min -273)+32;
        end

        function temp=currenttemp(app,dataf)
        %This function finds the current temperature and converts it into
        %Fahrenheit

        try 
            tempdata = dataf{1}.main.temp;
        catch ME
            tempdata = dataf(1).main.temp;
        end

        temp = 1.8 * (tempdata - 273) + 32;
        end

        function [windmat,cloudmat]=windcloudata(app,datat)
        %This function stores wind and cloud data in a matrix
        windmat=zeros(1,8);
        cloudmat=zeros(1,8);
        for j = 1:8
            try 
                windspeed = datat.list{j}.wind.speed;
            catch ME
                windspeed = datat.list(j).wind.speed;
        end
    
        windmat(1,j) = windspeed;
        
        try
             cloudata = datat.list{j}.clouds.all;
        catch ME
             cloudata = datat.list(j).clouds.all;
        end
        cloudmat(1,j)= cloudata;
        end
        end
        
        function q = citystuff(app,value)
            if isempty(value) == 0
                q = strcat(value,',us');
            else
                q = 'Boston,us';
            end
        end
        
        function tempmat = tempday(app,datat)
        %This function store the temperatures for a 24 hour interval in a matrix
        tempmat=zeros(1,8);
        for j=1:8
    
        try 
            temperature = datat.list{j}.main.temp;
        catch ME
            temperature = datat.list(j).main.temp;
        end
        tempmat(1,j)=temperature;
        end
        end
        
        function [seamat,groundmat]=seagrnddata(app,datat)
        seamat=zeros(1,8);
        groundmat=zeros(1,8);
        for j = 1:8
            try 
                seadata = datat.list{j}.main.sea_level;
            catch ME
                seadata = datat.list(j).main.sea_level;
            end
    
            seamat(1,j) = seadata;
        
            try
                 groundata = datat.list{j}.main.grnd_level;
            catch ME
                 groundata = datat.list(j).main.grnd_level;
            end
        
            groundmat(1,j)= groundata;
        end
        end


        %Useful Contacts Button
        function UsefulContacts(app,ContactButton)
            string = {'Police - 911';'Uber - 800-353-8237';'Ambulance - 800-400-0689';'National Sexual Assault Hotline - 1-800-656-4673';'American Association of Poison Control Centers - 800-222-1222';'National Suicide Prevention Lifeline - 1-800-273-8255';'National Hotline for Crime Victims - 1-855-484-2846';'Disaster Distress Helpline - 1-800-985-5990';'Identity Theft Resource Center - 1-888-400-5530';'National Advocacy for Local LGBT Communities - 1-212-714-1141';'National Domestic Violence Hotline - 1-800-799-7233';'Overseas Citizens Services - 1-888-407-4747';'American Family Housing/Sheltering - 1-888-600-4357'};
            if ContactButton == 1
                str = load('str.mat');
                d = dialog('Position',[300 250 500 325]);
            
                txt = uicontrol('Parent',d,...
                       'Style','text',...
                       'Position',[55 -35 400 300],...
                       'String',string);
            end
        end
        
        %Map Display Button
        function MapDisplay(app,lat,lon,value)
            %This function opens a map
            if value == 1
                url1=sprintf('http://openweathermap.org/weathermap?basemap=map&cities=false&layer=precipitation&lat=%2.4f&lon=%2.4f&zoom=1000',lat,lon);
                web(url1);
            end
        end
        
        %Immediate Threats Button
        function ImmediateThreats(app,cloudmat,windmat,tempmat,seamat,groundmat,value123,datat,cityData)
            if value123 == 1
                %This function stores wind and cloud data in a matrix
                windmat=zeros(1,8);
                cloudmat=zeros(1,8);
                for j = 1:8
                    try 
                        windspeed = datat.list{j}.wind.speed;
                    catch ME
                        windspeed = datat.list(j).wind.speed;
                    end
    
                    windmat(1,j) = windspeed;
        
                    try
                         cloudata = datat.list{j}.clouds.all;
                    catch ME
                         cloudata = datat.list(j).clouds.all;
                    end
        
              cloudmat(1,j)= cloudata;
              avewindspeed=sum(windmat(1:8))/8;
                  if avewindspeed>=70
                      app.TextArea.Value = 'Most likely category 5 hurricane level wind speeds. Please evacuate immediately.';
                  elseif avewindspeed>=69 && avewindspeed<=58
                      app.TextArea.Value ='Most likely category 4 hurricane level wind speeds. Please seek shelter/evacuate immediately.';
                  elseif avewindspeed>=57 && avewindspeed<=50
                      app.TextArea.Value = 'Most likely category 3 hurricane level wind speeds. Please seek shelter.';
                  elseif avewindspeed>=49 && avewindspeed<=43
                      app.TextArea.Value = 'Most likely category 2 hurricane level wind speeds. Be careful.';
                  elseif avewindspeed>=42 && avewindspeed<=33
                      app.TextArea.Value = 'Most likely category 1 hurricane level wind speeds. Stay safe.';
                  else 
                      app.TextArea.Value = 'No immediate threats detected! Wind speeds are normal.';
                  end
                end
                %This will plot the wind speeds
           
                fig1 = uifigure(1);
                ax1 = uiaxes(fig1);
                plot(ax1,1:8,windmat(1:8),'r')
                titleinfo=sprintf('Wind Speeds at three hour intervals for %s\n',cityData);
                ax1.Title.String=titleinfo;
                ax1.XLabel.String='Hour (3 hour intervals for 24 hour day)';
                ax1.YLabel.String='Wind Speeds (m/s)';

                %This will plot the temperature speeds
                fig2 = uifigure(2);
                ax2 = uiaxes(fig2);
                plot(ax2,1:8,tempmat(1:8),'g');
                titleinfo2=sprintf('Temperature at three hour intervals for %s\n',cityData);
                ax2.Title.String=titleinfo2;
                ax2.XLabel.String='Hour (3 hour intervals for 24 hour day)';
                ax2.YLabel.String = 'Temperature in Fahrenheit';

                %This will plot the sea level
                fig3 = uifigure(3);
                ax3 = uiaxes(fig3);
                plot(ax3,1:8,seamat(1:8),'b');
                titleinfo3=sprintf('Sea Levels at three hour intervals for %s\n',cityData);
                ax3.Title.String=titleinfo3;
                ax3.XLabel.String='Hour (3 hour intervals for 24 hour day)';
                ax3.YLabel.String = 'Sea Levels in hPa';

                %This will plot the ground level
                fig4 = uifigure(4);
                ax4 = uiaxes(fig4);
                plot(ax4,1:8,groundmat(1:8),'y');
                titleinfo4=sprintf('Altitude pressure at three hour intervals for %s\n',cityData);
                ax4.Title.String=titleinfo4;
                ax4.XLabel.String='Hour (3 hour intervals for 24 hour day)';
                ax4.YLabel.String = 'Altitude Pressure in hPa';
            end
        end
    end


    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %Main Script
            
    %This app is called emerENGcy and it is an application that provides
    %information about the weather, stores contact information and informs the
    %user on extreme weather conditions.
    APPID = 'e1e43fc2a743b11f74756e4c1803fd9e';
    endpoint1 = 'http://api.openweathermap.org/data/2.5/weather';
    endpoint2  = 'http://api.openweathermap.org/data/2.5/forecast';
    q = citystuff(app,app.USCityEditField.Value);
    [dataf,datat] = callapi(app,APPID,q,endpoint1,endpoint2);
    [lon,lat] = coordinates(app,dataf);
    [tempmax,tempmin] = tempmaxmin(app,dataf);
    temp = currenttemp(app,dataf);
    [windmat,cloudmat] = windcloudata(app,datat);
    tempmat = tempday(app,datat);
    [seamat,groundmat]=seagrnddata(app,datat);
    
    %Button Function Calls
    Weatherapp(app,app.WeatherButton.Value,app.USCityEditField.Value,tempmin,tempmax)
    UsefulContacts(app,app.UsefulContactsButton.Value)
    MapDisplay(app,lat,lon,app.MapDisplayButton.Value)
    ImmediateThreats(app,cloudmat,windmat,tempmat,seamat,groundmat,app.ImmediateThreatsButton.Value,datat,app.USCityEditField.Value)
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
           
        end

        % Value changed function: USCityEditField
        function USCityEditFieldValueChanged(app, event)
            value = app.USCityEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.USCityEditFieldLabel = uilabel(app.UIFigure);
            app.USCityEditFieldLabel.Text = 'US City';
            app.USCityEditField = uieditfield(app.UIFigure, 'text');
            app.USCityEditField.ValueChangedFcn = createCallbackFcn(app, @USCityEditFieldValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ImmediateThreatsButton = uitogglebutton(app.ButtonGroup);
            app.ImmediateThreatsButton.Text = 'Immediate Threats';
            app.ImmediateThreatsButton.Value = true;
            app.MapDisplayButton = uitogglebutton(app.ButtonGroup);
            app.MapDisplayButton.Text = 'Map Display';
            app.UsefulContactsButton = uitogglebutton(app.ButtonGroup);
            app.UsefulContactsButton.Text = 'Useful Contacts';
            app.WeatherButton = uitogglebutton(app.ButtonGroup);
            app.WeatherButton.Text = 'Weather';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end